classdef DigitalNumberAdaptationTask_MonitorEye < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StartPracticeButton            matlab.ui.control.Button
        DebugModeDropDown              matlab.ui.control.DropDown
        DebugModeDropDownLabel         matlab.ui.control.Label
        DateDatePicker                 matlab.ui.control.DatePicker
        DateDatePickerLabel            matlab.ui.control.Label
        TrialsDoneLabel                matlab.ui.control.Label
        TrialsTotalLabel               matlab.ui.control.Label
        CalibrationButton              matlab.ui.control.Button
        StartTaskButton                matlab.ui.control.Button
        StimuliParametersPanel         matlab.ui.container.Panel
        StimuliTypeDropDown            matlab.ui.control.DropDown
        StimuliTypeDropDownLabel       matlab.ui.control.Label
        degreeLabel_2                  matlab.ui.control.Label
        EccentricityEditField          matlab.ui.control.NumericEditField
        EccentricityEditFieldLabel     matlab.ui.control.Label
        StimulusHeightEditField        matlab.ui.control.NumericEditField
        StimulusHeightEditFieldLabel   matlab.ui.control.Label
        degreeLabel                    matlab.ui.control.Label
        DefaultParametersPanel         matlab.ui.container.Panel
        mmLabel_2                      matlab.ui.control.Label
        MonitorHeightEditField         matlab.ui.control.NumericEditField
        MonitorHeightEditFieldLabel    matlab.ui.control.Label
        mmLabel                        matlab.ui.control.Label
        ViewingDistanceEditField       matlab.ui.control.NumericEditField
        ViewingDistanceEditFieldLabel  matlab.ui.control.Label
        TaskParametersPanel            matlab.ui.container.Panel
        SessionNoEditField             matlab.ui.control.NumericEditField
        SessionNoEditFieldLabel        matlab.ui.control.Label
        TestStiTimeEditField           matlab.ui.control.NumericEditField
        TestStiTimeEditFieldLabel      matlab.ui.control.Label
        secondLabel_5                  matlab.ui.control.Label
        TopupAptTimeEditField          matlab.ui.control.NumericEditField
        TopupAptTimeEditFieldLabel     matlab.ui.control.Label
        secondLabel_4                  matlab.ui.control.Label
        ISIEditField                   matlab.ui.control.NumericEditField
        ISIEditFieldLabel              matlab.ui.control.Label
        secondLabel_3                  matlab.ui.control.Label
        RepeatEditField                matlab.ui.control.NumericEditField
        RepeatEditFieldLabel           matlab.ui.control.Label
        ITIEditField                   matlab.ui.control.NumericEditField
        ITIEditFieldLabel              matlab.ui.control.Label
        secondLabel_2                  matlab.ui.control.Label
        OnsetAptTimeEditField          matlab.ui.control.NumericEditField
        OnsetAptTimeEditFieldLabel     matlab.ui.control.Label
        secondLabel                    matlab.ui.control.Label
        SubjectInformationPanel        matlab.ui.container.Panel
        GenderDropDown                 matlab.ui.control.DropDown
        GenderDropDownLabel            matlab.ui.control.Label
        VisionCorrectionCheckBox       matlab.ui.control.CheckBox
        AgeEditField                   matlab.ui.control.EditField
        AgeEditFieldLabel              matlab.ui.control.Label
        IDEditField                    matlab.ui.control.EditField
        IDEditFieldLabel               matlab.ui.control.Label
        UIAxesEyePosition              matlab.ui.control.UIAxes
        UIAxesAdaptationTask           matlab.ui.control.UIAxes
        UIAxesReactionTime             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalibrationButton
        function CalibrationButtonPushed(app, event)
            GazeCalibration;
        end

        % Button pushed function: StartPracticeButton
        function StartPracticeButtonPushed(app, event)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%% stimulus information %%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            debugFlag = app.DebugModeDropDown.Value;
            viewDistance = app.ViewingDistanceEditField.Value;
            monitorHeight = app.MonitorHeightEditField.Value;

            onsetAdaptTime = app.OnsetAptTimeEditField.Value;
            topupAdaptTime = app.TopupAptTimeEditField.Value;
            testStiTime = app.TestStiTimeEditField.Value;
            ISI = app.ISIEditField.Value;
            app.ITIEditField.Value = 0.5;
            ITI = app.ITIEditField.Value;
            repeats = app.RepeatEditField.Value;
            
            stiHeight = app.StimulusHeightEditField.Value;
            eccentricity = app.EccentricityEditField.Value;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%% screen configuration %%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if strcmp(debugFlag, 'On')
                Screen('Preference', 'SkipSyncTests', 2);
            end
            Screen('Preference', 'VisualDebuglevel', 3);
            PsychDefaultSetup(2);

            screenID = max(Screen('Screens'));
            PsychImaging('PrepareConfiguration');
            PsychImaging('AddTask', 'General', 'FloatingPoint32BitIfPossible');
            [win, rect] = PsychImaging('OpenWindow', screenID, 0.5, [1920, 0, 3840, 1080], [], [], [], 2);
            [center(1), center(2)] = RectCenter(rect);
            [width, height] = RectSize(rect);

            Screen('BlendFunction', win, GL_SRC_ALPHA, GL_ONE_MINUS_SRC_ALPHA);

            ifi = Screen('GetFlipInterval', win); % s/frame
            ppd = height/2/atand(monitorHeight/viewDistance/2); % pixel/degree
            ppm = height/monitorHeight; % pixel/mm

            waitframes = 1;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%% eye tracking parameters %%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if strcmp(debugFlag, 'Off')
                fixArea = 3; % degree
                fixArea = fixArea*ppd;
                ringRadius = fixArea/2;

                bufferDuration = 30; % ms
                crsLiveTrackInit;
                crsLiveTrackSetResultsTypeCalibrated;
                crsLiveTrackStartTracking;
                [~, ~, sampleRate, ~, ~, ~] = crsLiveTrackGetCaptureConfig;
                bufferLength = round((bufferDuration/1000)*sampleRate);
            end

            fixWaitTime = 0.5; % second
            fixWaitFrames = fixWaitTime/ifi;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%% stimuli preparation %%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            adaptShiftTime = 1; % seconds

            onsetAdaptFrames = round(onsetAdaptTime/ifi);
            topupAdaptFrames = round(topupAdaptTime/ifi);
            adaptShiftFrames = round(adaptShiftTime/ifi);
            ISIFrames = round(ISI/ifi);
            testStiFrames = round(testStiTime/ifi);
            ITIFrames = round(ITI/ifi);

            stiHeight = stiHeight*ppd;
            eccentricity = eccentricity*ppd;

            [digitalNumbertex, positionRect, alphaSet, masktex, maskDstRect] = DigitalNumStiSetup(win, rect, stiHeight);

            totalShiftX = eccentricity;
            totalShiftY = 0;
            totalShifts = repmat([totalShiftX; totalShiftY], 2, 1);

            fixationCrossLength = 0.5; % degree
            fixationCrossLength = fixationCrossLength*ppd; % pixel
            crossDstRect = [0, 0, -fixationCrossLength/2, fixationCrossLength/2; -fixationCrossLength/2, fixationCrossLength/2, 0, 0];

            KbName('UnifyKeyNames');
            escapeKey = KbName('escape');
            escapeFlag = 0;
            gamepadIndex = 1;

            InitializePsychSound;
            beep = sin((1:3000)/5);
            correctSound = sin((1:3000)/3);
            falseSound = sin((1:3000)/10);
            beephandle = PsychPortAudio('Open', [], [], 0, [], 2);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%% task start %%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            reactTime = [];
            for visualField = 1:2

                DrawFormattedText(win, 'Press "A" button to start.', 'center', 'center', 1, [], [], [], 3);
                vbl = Screen('Flip', win);
                while 1
                    if Gamepad('GetButton', gamepadIndex, 3)
                        break;
                    end
                end

                if visualField == 1
                    shiftSign = 1;
                elseif visualField == 2
                    shiftSign = -1;
                end

                adaptSti = randi(9);
                testStiSelect = [1, 2, 3, 4, 5, 7, 9];
                maskShiftSelect = [-17, 0, 17, 34];
                conditionTotal = [];
                for i = 1:repeats % number of trials is three times of repeats
                    testSti = [6, 8, testStiSelect(randi(length(testStiSelect)))];
                    maskShift = [maskShiftSelect(end), maskShiftSelect(end), maskShiftSelect(randi(length(maskShiftSelect)))];
                    if testSti(end)==5 || testSti(end)==9
                        maskShift(end) = maskShiftSelect(end);
                    end
                    conditions = [testSti; maskShift];
                    conditionTotal = [conditionTotal, conditions(:, randperm(length(testSti)))]; %#ok<AGROW> 
                end
                trialNum = size(conditionTotal, 2);
                app.TrialsTotalLabel.Text = strcat({'Trials Total: '}, {' '}, {num2str(trialNum)});
                drawnow;

                onsetShiftTimes = round(onsetAdaptTime/adaptShiftTime);
                radiusAdapt = eccentricity/2*rand(1, onsetShiftTimes);
                angleAdapt = 360*rand(1, onsetShiftTimes);
                totalShiftXAdapt = radiusAdapt.*cosd(angleAdapt);
                totalShiftYAdapt = radiusAdapt.*sind(angleAdapt);
                totalShiftsAdapt = repmat([totalShiftXAdapt; totalShiftYAdapt], 2, 1);
                totalShiftsAdapt = kron(totalShiftsAdapt, ones(1, adaptShiftFrames));
                eyePositionsX = nan(1, onsetAdaptFrames);
                eyePositionsY = nan(1, onsetAdaptFrames);
                eyeTrackerCount = 1;
                for i = 1:onsetAdaptFrames

                    if strcmp(debugFlag, 'Off')
                        Data = crsLiveTrackGetLatestEyePosition(bufferLength);
                        eyePosition(1) = round(mean(Data.mmPositions(:,1))*ppm + width/2);
                        eyePosition(2) = round(mean(Data.mmPositions(:,2))*ppm + height/2);

                        eyePositionsX(eyeTrackerCount) = eyePosition(1);
                        eyePositionsY(eyeTrackerCount) = eyePosition(2);
                    end

                    Screen('DrawTextures', win, digitalNumbertex, [],...
                        positionRect + totalShiftsAdapt(:, i) + shiftSign*totalShifts, [], [], alphaSet(adaptSti, :), [], [], []);
                    Screen('DrawLines', win, crossDstRect, 3, [1, 1, 1], center, 2, 1);
                    Screen('Flip', win, vbl+(waitframes-0.5)*ifi);

                    eyeTrackerCount = eyeTrackerCount + 1;

                    [~, ~, keyCode, ~] = KbCheck;
                    if keyCode(escapeKey)
                        escapeFlag = 1;
                        break;
                    end
                end

                if escapeFlag
                    break;
                end

                if strcmp(debugFlag, 'Off')
                    crossX = [-fixationCrossLength/2, 0; fixationCrossLength/2, 0] + center(1);
                    crossY = [0, -fixationCrossLength/2; 0, fixationCrossLength/2] + center(2);
                    plot(app.UIAxesEyePosition, crossX, crossY, '-k');
                    hold(app.UIAxesEyePosition, 'on');
                    angles = linspace(0, 2*pi, 300);
                    circleX = ringRadius*cos(angles) + center(1);
                    circleY = ringRadius*sin(angles) + center(2);
                    plot(app.UIAxesEyePosition, circleX, circleY, '-g');
                    plot(app.UIAxesEyePosition, eyePositionsX, eyePositionsY);
                    xlim(app.UIAxesEyePosition, [width/3, width*2/3]);
                    ylim(app.UIAxesEyePosition, [height/3, height*2/3]);
                    hold(app.UIAxesEyePosition, 'off');
                end
                drawnow;

                ISIAfterOnset = 0.5; % seconds
                ISIAfterOnsetFrames = round(ISIAfterOnset/ifi);
                for i = 1:ISIAfterOnsetFrames
                    Screen('FillRect', win, 0.5);
                    Screen('DrawLines', win, crossDstRect, 3, [1, 1, 1], center, 2, 1);
                    Screen('Flip', win);
                end

                reactTimeWindow = 1.5;
                reactTimeCount = 1;
                n = 1;
                while n<=trialNum

                    PsychPortAudio('FillBuffer', beephandle, [beep; beep]);
                    PsychPortAudio('Start', beephandle, 1, 0, 1);
                    for i = 1:fixWaitFrames
                        Screen('FillRect', win, 0.5);
                        Screen('DrawLines', win, crossDstRect, 3, [1, 1, 1], center, 2, 1);
                        vbl = Screen('Flip', win, vbl+(waitframes-0.5)*ifi);
                    end

                    testStiCurr = conditionTotal(1, n);
                    maskShiftCurr = conditionTotal(2, n);

                    topupShiftTimes = round(topupAdaptTime/adaptShiftTime);
                    radiusAdapt = eccentricity/2*rand(1, topupShiftTimes);
                    angleAdapt = 360*rand(1, topupShiftTimes);
                    totalShiftXAdapt = radiusAdapt.*cosd(angleAdapt);
                    totalShiftYAdapt = radiusAdapt.*sind(angleAdapt);
                    totalShiftsAdapt = repmat([totalShiftXAdapt; totalShiftYAdapt], 2, 1);
                    totalShiftsAdapt = kron(totalShiftsAdapt, ones(1, adaptShiftFrames));
                    eyePositionsX = nan(1, topupAdaptFrames);
                    eyePositionsY = nan(1, topupAdaptFrames);
                    eyeTrackerCount = 1;
                    for i = 1:topupAdaptFrames

                        if strcmp(debugFlag, 'Off')
                            Data = crsLiveTrackGetLatestEyePosition(bufferLength);
                            eyePosition(1) = round(mean(Data.mmPositions(:,1))*ppm + width/2);
                            eyePosition(2) = round(mean(Data.mmPositions(:,2))*ppm + height/2);

                            eyePositionsX(eyeTrackerCount) = eyePosition(1);
                            eyePositionsY(eyeTrackerCount) = eyePosition(2);
                        end

                        Screen('DrawTextures', win, digitalNumbertex, [],...
                            positionRect + totalShiftsAdapt(:, i) + shiftSign*totalShifts, [], [], alphaSet(adaptSti, :), [], [], []);
                        Screen('DrawLines', win, crossDstRect, 3, [1, 1, 1], center, 2, 1);
                        vbl = Screen('Flip', win, vbl+(waitframes-0.5)*ifi);

                        eyeTrackerCount = eyeTrackerCount + 1;

                        [~, ~, keyCode, ~] = KbCheck;
                        if keyCode(escapeKey)
                            escapeFlag = 1;
                            break;
                        end
                    end

                    if escapeFlag
                        break;
                    end

                    if strcmp(debugFlag, 'Off')
                        crossX = [-fixationCrossLength/2, 0; fixationCrossLength/2, 0] + center(1);
                        crossY = [0, -fixationCrossLength/2; 0, fixationCrossLength/2] + center(2);
                        plot(app.UIAxesEyePosition, crossX, crossY, '-k');
                        hold(app.UIAxesEyePosition, 'on');
                        angles = linspace(0, 2*pi, 300);
                        circleX = ringRadius*cos(angles) + center(1);
                        circleY = ringRadius*sin(angles) + center(2);
                        plot(app.UIAxesEyePosition, circleX, circleY, '-g');
                        plot(app.UIAxesEyePosition, eyePositionsX, eyePositionsY);
                        xlim(app.UIAxesEyePosition, [width/3, width*2/3]);
                        ylim(app.UIAxesEyePosition, [height/3, height*2/3]);
                        hold(app.UIAxesEyePosition, 'off');
                    end
                    drawnow;

                    for i = 1:ISIFrames
                        Screen('FillRect', win, 0.5);
                        Screen('DrawLines', win, crossDstRect, 3, [1, 1, 1], center, 2, 1);
                        Screen('Flip', win);
                    end

                    offsetShift = [maskShiftCurr, 0, maskShiftCurr, 0]';
                    for i = 1:testStiFrames
                        Screen('DrawTextures', win, digitalNumbertex, [],...
                            positionRect + shiftSign*totalShifts, [], [], alphaSet(testStiCurr, :), [], [], []);
                        Screen('DrawTextures', win, masktex, [], maskDstRect + shiftSign*totalShifts + offsetShift, [], [], [], [1, 1, 1, 1], [], []);
                        Screen('DrawLines', win, crossDstRect, 3, [1, 1, 1], center, 2, 1);
                        Screen('Flip', win);
                    end

                    beforeResponseTime = 0.3; % second
                    beforeResponseFrames = round(beforeResponseTime/ifi);
                    for i = 1:beforeResponseFrames
                        Screen('FillRect', win, 0.5);
                        Screen('DrawLines', win, crossDstRect, 3, [1, 1, 1], center, 2, 1);
                        Screen('Flip', win);
                    end

                    DrawFormattedText(win, '6   ←   "L"                8   →   "R"\n"A" other numbers', 'center', 'center', 1, [], [], [], 3)
                    Screen('Flip', win);
                    timeBeforePress = GetSecs;
                    while 1
                        [~, ~, keyCode, ~] = KbCheck;
                        LKeyPress = Gamepad('GetButton', gamepadIndex, 5);
                        RKeyPress = Gamepad('GetButton', gamepadIndex, 6);
                        AKeyPress = Gamepad('GetButton', gamepadIndex, 3);
                        if LKeyPress
                            response = 6;
                            break;
                        elseif RKeyPress
                            response = 8;
                            break;
                        elseif AKeyPress
                            response = inf;
                            break;
                        elseif keyCode(escapeKey)
                            escapeFlag = 1;
                            break;
                        end
                        timeDuringPress = GetSecs;
                        if (timeDuringPress - timeBeforePress)>reactTimeWindow
                            response = nan;
                            PsychPortAudio('FillBuffer', beephandle, [falseSound; falseSound]);
                            PsychPortAudio('Start', beephandle, 1, 0, 1);
                            n = n - 1;
                            break;
                        end
                    end
                    timeAfterPress = GetSecs;

                    if escapeFlag
                        break;
                    end

                    reactTime(visualField, reactTimeCount) = timeAfterPress - timeBeforePress; %#ok<AGROW> 
                    if (response~=inf && response==testStiCurr) || (response==inf && testStiCurr~=6 && testStiCurr~=8)
                        PsychPortAudio('FillBuffer', beephandle, [correctSound; correctSound]);
                        PsychPortAudio('Start', beephandle, 1, 0, 1);
                    else
                        PsychPortAudio('FillBuffer', beephandle, [falseSound; falseSound]);
                        PsychPortAudio('Start', beephandle, 1, 0, 1);
                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %%%%%%%%%%%%%%%% results illustration %%%%%%%%%%%%%%%%%%%%%
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    if visualField==1
                        plot(app.UIAxesReactionTime, 1:n, reactTime(1, 1:n), 'o-r');
                        ylim(app.UIAxesReactionTime, [0, 1.5]);
                    elseif visualField==2
                        plot(app.UIAxesReactionTime, 1:trialNum, reactTime(1, :), 'o-r');
                        hold(app.UIAxesReactionTime, "on");
                        plot(app.UIAxesReactionTime, trialNum + 1:trialNum + n, reactTime(2, 1:n), 'o-g');
                        hold(app.UIAxesReactionTime, "off");
                        ylim(app.UIAxesReactionTime, [0, 1.5]);
                    end
                    drawnow;

                    for i = 1:ITIFrames
                        Screen('FillRect', win, 0.5);
                        Screen('Flip', win);
                    end

                    reactTimeCount = reactTimeCount + 1;
                    n = n + 1;

                end

                if escapeFlag
                    break;
                end

            end

            PsychPortAudio('Stop', beephandle);
            PsychPortAudio('Close', beephandle);

            if strcmp(debugFlag, 'Off')
                crsLiveTrackStopTracking;
                crsLiveTrackClearDataBuffer;
            end

            sca;

        end

        % Button pushed function: StartTaskButton
        function StartTaskButtonPushed(app, event)
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%% subject information %%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            taskDate = app.DateDatePicker.Value;
            subjectID = app.IDEditField.Value;
            subjectAge = app.AgeEditField.Value;
            subjectGender = app.GenderDropDown.Value;
            visionCorrection = app.VisionCorrectionCheckBox.Value;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%% stimulus information %%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            debugFlag = app.DebugModeDropDown.Value;
            viewDistance = app.ViewingDistanceEditField.Value;
            monitorHeight = app.MonitorHeightEditField.Value;

            onsetAdaptTime = app.OnsetAptTimeEditField.Value;
            topupAdaptTime = app.TopupAptTimeEditField.Value;
            testStiTime = app.TestStiTimeEditField.Value;
            ISI = app.ISIEditField.Value;
            app.ITIEditField.Value = 0.5;
            ITI = app.ITIEditField.Value;
            repeats = app.RepeatEditField.Value;
            sessionNum = app.SessionNoEditField.Value;
            
            stiType = app.StimuliTypeDropDown.Value;
            stiHeight = app.StimulusHeightEditField.Value;
            eccentricity = app.EccentricityEditField.Value;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%% screen configuration %%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if strcmp(debugFlag, 'On')
                Screen('Preference', 'SkipSyncTests', 2);
            end
            Screen('Preference', 'VisualDebuglevel', 3);
            PsychDefaultSetup(2);

            screenID = max(Screen('Screens'));
            PsychImaging('PrepareConfiguration');
            PsychImaging('AddTask', 'General', 'FloatingPoint32BitIfPossible');
            [win, rect] = PsychImaging('OpenWindow', screenID, 0.5, [1920, 0, 3840, 1080], [], [], [], 2);
            [center(1), center(2)] = RectCenter(rect);
            [width, height] = RectSize(rect);

            Screen('BlendFunction', win, GL_SRC_ALPHA, GL_ONE_MINUS_SRC_ALPHA);

            ifi = Screen('GetFlipInterval', win); % s/frame
            ppd = height/2/atand(monitorHeight/viewDistance/2); % pixel/degree
            ppm = height/monitorHeight; % pixel/mm

            waitframes = 1;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%% eye tracking parameters %%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if strcmp(debugFlag, 'Off')
                fixArea = 3; % degree
                fixArea = fixArea*ppd;
                ringRadius = fixArea/2;

                bufferDuration = 30; % ms
                crsLiveTrackInit;
                crsLiveTrackSetResultsTypeCalibrated;
                crsLiveTrackStartTracking;
                [~, ~, sampleRate, ~, ~, ~] = crsLiveTrackGetCaptureConfig;
                bufferLength = round((bufferDuration/1000)*sampleRate);
            end

            fixWaitTime = 0.5; % second
            fixWaitFrames = fixWaitTime/ifi;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%% stimuli preparation %%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            adaptShiftTime = 1; % seconds

            onsetAdaptFrames = round(onsetAdaptTime/ifi);
            topupAdaptFrames = round(topupAdaptTime/ifi);
            adaptShiftFrames = round(adaptShiftTime/ifi);
            ISIFrames = round(ISI/ifi);
            testStiFrames = round(testStiTime/ifi);
            ITIFrames = round(ITI/ifi);

            stiHeight = stiHeight*ppd;
            eccentricity = eccentricity*ppd;

            [digitalNumbertex, positionRectDigital, alphaSet, masktex, maskDstRect] = DigitalNumStiSetup(win, rect, stiHeight); % digital numbers and simple shapes

            [normalNum6, ~, normalAlpha6] = imread('arabicNumber6.png');
            normalNum6(:, :, 4) = normalAlpha6;
            [normalNum8, ~, normalAlpha8] = imread('arabicNumber8.png');
            normalNum8(:, :, 4) = normalAlpha8;
            normalNum6Index = Screen('MakeTexture', win, normalNum6);
            normalNum8Index = Screen('MakeTexture', win, normalNum8);
            normalNumIndex = [normalNum6Index, normalNum8Index];
            imgRectNormal6 = Screen('Rect', normalNum6Index)*0.95;
            positionRectNormalNum = CenterRect(imgRectNormal6, rect);

            [mojiNum6, ~, mojiAlpha6] = imread('mojiNumber6.png');
            mojiNum6(:, :, 4) = mojiAlpha6;
            [mojiNum8, ~, mojiAlpha8] = imread('mojiNumber8.png');
            mojiNum8(:, :, 4) = mojiAlpha8;
            mojiNum6Index = Screen('MakeTexture', win, mojiNum6);
            mojiNum8Index = Screen('MakeTexture', win, mojiNum8);
            mojiNumIndex = [mojiNum6Index, mojiNum8Index];
            imgRectNormal6 = Screen('Rect', mojiNum6Index)*0.95;
            positionRectMojiNum = CenterRect(imgRectNormal6, rect);

            [scrambleNum6, ~, scrambleAlpha6] = imread('scrambleNumber6.png');
            scrambleNum6(:, :, 4) = scrambleAlpha6;
            [scrambleNum8, ~, scrambleAlpha8] = imread('scrambleNumber8.png');
            scrambleNum8(:, :, 4) = scrambleAlpha8;
            scrambleNum6Index = Screen('MakeTexture', win, scrambleNum6);
            scrambleNum8Index = Screen('MakeTexture', win, scrambleNum8);
            scrambleNumIndex = [scrambleNum6Index, scrambleNum8Index];
            imgRectscramble6 = Screen('Rect', scrambleNum6Index)*0.95;
            positionRectScrambleNum = CenterRect(imgRectscramble6, rect);

            totalShiftX = eccentricity;
            totalShiftY = 0;
            totalShifts = repmat([totalShiftX; totalShiftY], 2, 1);

            adaptStiSelect = [6, 8, 8, 6];
            shiftSignSelect = [1, -1, 1, -1]; % divided into four sessions
            adaptSti = adaptStiSelect(sessionNum);
            shiftSign = shiftSignSelect(sessionNum);
            testSti = [6, 6, 8];
            maskShifts = [0, 34, 34];
            conditionTotal = [];
            for i = 1:repeats % number of trials is three times of repeats
                conditions = [adaptSti*ones(1, length(testSti)); testSti; maskShifts];
                conditionTotal = [conditionTotal, conditions(:, randperm(length(testSti)))]; %#ok<AGROW> 
            end
            trialNum = size(conditionTotal, 2);
            app.TrialsTotalLabel.Text = strcat({'Trials Total: '}, {' '}, {num2str(trialNum)});
            drawnow;

            fixationCrossLength = 0.5; % degree
            fixationCrossLength = fixationCrossLength*ppd; % pixel
            crossDstRect = [0, 0, -fixationCrossLength/2, fixationCrossLength/2; -fixationCrossLength/2, fixationCrossLength/2, 0, 0];

            KbName('UnifyKeyNames');
            escapeKey = KbName('escape');
            escapeFlag = 0;
            gamepadIndex = 1;

            InitializePsychSound;
            beep = sin((1:3000)/5);
            beephandle = PsychPortAudio('Open', [], [], 0, [], 2);
            PsychPortAudio('FillBuffer', beephandle, [beep; beep]);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%% task start %%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            DrawFormattedText(win, 'Press "A" button to start.', 'center', 'center', 1, [], [], [], 3);
            vbl = Screen('Flip', win);
            while 1
                if Gamepad('GetButton', gamepadIndex, 3)
                    break;
                end
            end

            adaptStiIndex = adaptSti;
            if (strcmp(stiType, 'ArabicNum') || strcmp(stiType, 'Moji') || strcmp(stiType, 'ScbShapes')) && adaptSti==6
                adaptStiIndex = 1;
            elseif (strcmp(stiType, 'ArabicNum') || strcmp(stiType, 'Moji') || strcmp(stiType, 'ScbShapes')) && adaptSti==8
                adaptStiIndex = 2;
            elseif strcmp(stiType, 'Shapes') && adaptSti==6
                adaptStiIndex = 11;
            elseif strcmp(stiType, 'Shapes') && adaptSti==8
                adaptStiIndex = 12;
            end

            onsetShiftTimes = round(onsetAdaptTime/adaptShiftTime);
            radiusAdapt = eccentricity*0.5*rand(1, onsetShiftTimes);
            angleAdapt = 360*rand(1, onsetShiftTimes);
            totalShiftXAdapt = radiusAdapt.*cosd(angleAdapt);
            totalShiftYAdapt = radiusAdapt.*sind(angleAdapt);
            totalShiftsAdapt = repmat([totalShiftXAdapt; totalShiftYAdapt], 2, 1);
            totalShiftsAdapt = kron(totalShiftsAdapt, ones(1, adaptShiftFrames));
            eyePositionsXO = nan(onsetAdaptFrames, 1);
            eyePositionsYO = nan(onsetAdaptFrames, 1);
            eyeTrackerCount = 1;
            for i = 1:onsetAdaptFrames

                if strcmp(debugFlag, 'Off')
                    Data = crsLiveTrackGetLatestEyePosition(bufferLength);
                    eyePosition(1) = round(mean(Data.mmPositions(:,1))*ppm + width/2);
                    eyePosition(2) = round(mean(Data.mmPositions(:,2))*ppm + height/2);
                    eyePositionsXO(eyeTrackerCount) = eyePosition(1);
                    eyePositionsYO(eyeTrackerCount) = eyePosition(2);
                end

                if strcmp(stiType, 'ArabicNum')
                    Screen('DrawTextures', win, normalNumIndex(adaptStiIndex), [],...
                        positionRectNormalNum' + totalShiftsAdapt(:, i) + shiftSign*[eccentricity, 0, eccentricity, 0]', [], [], [], [], [], []);
                elseif strcmp(stiType, 'Moji')
                    Screen('DrawTextures', win, mojiNumIndex(adaptStiIndex), [],...
                        positionRectMojiNum' + totalShiftsAdapt(:, i) + shiftSign*[eccentricity, 0, eccentricity, 0]', [], [], [], [], [], []);
                elseif strcmp(stiType, 'ScbShapes')
                    Screen('DrawTextures', win, scrambleNumIndex(adaptStiIndex), [],...
                        positionRectScrambleNum' + totalShiftsAdapt(:, i) + shiftSign*[eccentricity, 0, eccentricity, 0]', [], [], [], [], [], []);
                else
                    Screen('DrawTextures', win, digitalNumbertex, [],...
                        positionRectDigital + totalShiftsAdapt(:, i) + shiftSign*totalShifts, [], [], alphaSet(adaptStiIndex, :), [], [], []);
                end
                Screen('DrawLines', win, crossDstRect, 3, [1, 1, 1], center, 2, 1);
                Screen('Flip', win);

                eyeTrackerCount = eyeTrackerCount + 1;

                [~, ~, keyCode, ~] = KbCheck;
                if keyCode(escapeKey)
                    escapeFlag = 1;
                    break;
                end
            end

            if strcmp(debugFlag, 'Off')
                crossX = [-fixationCrossLength/2, 0; fixationCrossLength/2, 0] + center(1);
                crossY = [0, -fixationCrossLength/2; 0, fixationCrossLength/2] + center(2);
                plot(app.UIAxesEyePosition, crossX, crossY, '-k');
                hold(app.UIAxesEyePosition, 'on');
                angles = linspace(0, 2*pi, 300);
                circleX = ringRadius*cos(angles) + center(1);
                circleY = ringRadius*sin(angles) + center(2);
                plot(app.UIAxesEyePosition, circleX, circleY, '-g');
                plot(app.UIAxesEyePosition, eyePositionsXO, eyePositionsYO);
                xlim(app.UIAxesEyePosition, [width/3, width*2/3]);
                ylim(app.UIAxesEyePosition, [height/3, height*2/3]);
                hold(app.UIAxesEyePosition, 'off');
            end
            drawnow;

            ISIAfterOnset = 0.5; % seconds
            ISIAfterOnsetFrames = round(ISIAfterOnset/ifi);
            for i = 1:ISIAfterOnsetFrames
                Screen('FillRect', win, 0.5);
                Screen('DrawLines', win, crossDstRect, 3, [1, 1, 1], center, 2, 1);
                Screen('Flip', win);
            end

            response = nan(1, trialNum);
            responseTime = nan(1, trialNum);
            eyePositionsXT = nan(topupAdaptFrames + ISIFrames + testStiFrames, trialNum);
            eyePositionsYT = nan(topupAdaptFrames + ISIFrames + testStiFrames, trialNum);
            for n = 1:trialNum

                PsychPortAudio('Start', beephandle, 1, 0, 1);
                for i = 1:fixWaitFrames
                    Screen('FillRect', win, 0.5);
                    Screen('DrawLines', win, crossDstRect, 3, [1, 1, 1], center, 2, 1);
                    vbl = Screen('Flip', win, vbl+(waitframes-0.5)*ifi);
                end

                testStiCurr = conditionTotal(2, n);
                maskShiftCurr = conditionTotal(3, n);

                topupShiftTimes = round(topupAdaptTime/adaptShiftTime);
                radiusAdapt = eccentricity*0.5*rand(1, topupShiftTimes);
                angleAdapt = 360*rand(1, topupShiftTimes);
                totalShiftXAdapt = radiusAdapt.*cosd(angleAdapt);
                totalShiftYAdapt = radiusAdapt.*sind(angleAdapt);
                totalShiftsAdapt = repmat([totalShiftXAdapt; totalShiftYAdapt], 2, 1);
                totalShiftsAdapt = kron(totalShiftsAdapt, ones(1, adaptShiftFrames));
                eyeTrackerCount = 1;
                for i = 1:topupAdaptFrames

                    if strcmp(debugFlag, 'Off')
                        Data = crsLiveTrackGetLatestEyePosition(bufferLength);
                        eyePosition(1) = round(mean(Data.mmPositions(:,1))*ppm + width/2);
                        eyePosition(2) = round(mean(Data.mmPositions(:,2))*ppm + height/2);
                        eyePositionsXT(eyeTrackerCount, n) = eyePosition(1);
                        eyePositionsYT(eyeTrackerCount, n) = eyePosition(2);
                    end
                    eyeTrackerCount = eyeTrackerCount + 1;

                    if strcmp(stiType, 'ArabicNum')
                        Screen('DrawTextures', win, normalNumIndex(adaptStiIndex), [],...
                            positionRectNormalNum' + totalShiftsAdapt(:, i) + shiftSign*[eccentricity, 0, eccentricity, 0]', [], [], [], [], [], []);
                    elseif strcmp(stiType, 'Moji')
                        Screen('DrawTextures', win, mojiNumIndex(adaptStiIndex), [],...
                            positionRectMojiNum' + totalShiftsAdapt(:, i) + shiftSign*[eccentricity, 0, eccentricity, 0]', [], [], [], [], [], []);
                    elseif strcmp(stiType, 'ScbShapes')
                        Screen('DrawTextures', win, scrambleNumIndex(adaptStiIndex), [],...
                            positionRectScrambleNum' + totalShiftsAdapt(:, i) + shiftSign*[eccentricity, 0, eccentricity, 0]', [], [], [], [], [], []);
                    else
                        Screen('DrawTextures', win, digitalNumbertex, [],...
                            positionRectDigital + totalShiftsAdapt(:, i) + shiftSign*totalShifts, [], [], alphaSet(adaptStiIndex, :), [], [], []);
                    end
                    Screen('DrawLines', win, crossDstRect, 3, [1, 1, 1], center, 2, 1);
                    vbl = Screen('Flip', win, vbl+(waitframes-0.5)*ifi);

                    [~, ~, keyCode, ~] = KbCheck;
                    if keyCode(escapeKey)
                        escapeFlag = 1;
                        break;
                    end
                end

                if escapeFlag
                    break;
                end 

                for i = 1:ISIFrames

                    if strcmp(debugFlag, 'Off')
                        Data = crsLiveTrackGetLatestEyePosition(bufferLength);
                        eyePosition(1) = round(mean(Data.mmPositions(:,1))*ppm + width/2);
                        eyePosition(2) = round(mean(Data.mmPositions(:,2))*ppm + height/2);
                        eyePositionsXT(eyeTrackerCount, n) = eyePosition(1);
                        eyePositionsYT(eyeTrackerCount, n) = eyePosition(2);
                    end
                    eyeTrackerCount = eyeTrackerCount + 1;

                    Screen('FillRect', win, 0.5);
                    Screen('DrawLines', win, crossDstRect, 3, [1, 1, 1], center, 2, 1);
                    Screen('Flip', win);
                end

                offsetShift = [maskShiftCurr, 0, maskShiftCurr, 0]';
                for i = 1:testStiFrames

                    if strcmp(debugFlag, 'Off')
                        Data = crsLiveTrackGetLatestEyePosition(bufferLength);
                        eyePosition(1) = round(mean(Data.mmPositions(:,1))*ppm + width/2);
                        eyePosition(2) = round(mean(Data.mmPositions(:,2))*ppm + height/2);
                        eyePositionsXT(eyeTrackerCount, n) = eyePosition(1);
                        eyePositionsYT(eyeTrackerCount, n) = eyePosition(2);
                    end
                    eyeTrackerCount = eyeTrackerCount + 1;

                    Screen('DrawTextures', win, digitalNumbertex, [],...
                        positionRectDigital + shiftSign*totalShifts, [], [], alphaSet(testStiCurr, :), [], [], []);
                    Screen('DrawTextures', win, masktex, [], maskDstRect + offsetShift + shiftSign*totalShifts, [], [], [], [1, 1, 1, 1], [], []);
                    Screen('DrawLines', win, crossDstRect, 3, [1, 1, 1], center, 2, 1);
                    Screen('Flip', win);
                end

                beforeResponseTime = 0.3; % second
                beforeResponseFrames = beforeResponseTime/ifi;
                for i = 1:beforeResponseFrames
                    Screen('FillRect', win, 0.5);
                    Screen('DrawLines', win, crossDstRect, 3, [1, 1, 1], center, 2, 1);
                    Screen('Flip', win);
                end

                DrawFormattedText(win, '6   ←   "L"                8   →   "R"\n"A" other numbers', 'center', 'center', 1, [], [], [], 3)
                Screen('Flip', win);
                timeBeforePress = GetSecs;
                while 1
                    [~, ~, keyCode, ~] = KbCheck;
                    LKeyPress = Gamepad('GetButton', gamepadIndex, 5);
                    RKeyPress = Gamepad('GetButton', gamepadIndex, 6);
                    AKeyPress = Gamepad('GetButton', gamepadIndex, 3);
                    if LKeyPress
                        response(n) = 6;
                        break;
                    elseif RKeyPress
                        response(n) = 8;
                        break;
                    elseif AKeyPress
                        response(n) = inf;
                        break;
                    elseif keyCode(escapeKey)
                        escapeFlag = 1;
                        break;
                    end
                end
                timeAfterPress = GetSecs;

                if escapeFlag
                    break;
                end

                responseTime(n) = timeAfterPress - timeBeforePress;

                if strcmp(debugFlag, 'Off')
                    crossX = [-fixationCrossLength/2, 0; fixationCrossLength/2, 0] + center(1);
                    crossY = [0, -fixationCrossLength/2; 0, fixationCrossLength/2] + center(2);
                    plot(app.UIAxesEyePosition, crossX, crossY, '-k');
                    hold(app.UIAxesEyePosition, 'on');
                    angles = linspace(0, 2*pi, 300);
                    circleX = ringRadius*cos(angles) + center(1);
                    circleY = ringRadius*sin(angles) + center(2);
                    plot(app.UIAxesEyePosition, circleX, circleY, '-g');
                    plot(app.UIAxesEyePosition, eyePositionsXT(:, n), eyePositionsYT(:, n));
                    xlim(app.UIAxesEyePosition, [width/3, width*2/3]);
                    ylim(app.UIAxesEyePosition, [height/3, height*2/3]);
                    hold(app.UIAxesEyePosition, 'off');
                end
                drawnow;

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%% results illustration %%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if n>=size(conditions, 2)
                    conditionDone = conditionTotal(:, 1:n);
                    responseDone = response(1:n);

                    responseO = responseDone(conditionDone(2, :)==testSti(1) & conditionDone(3, :)==maskShifts(1));
                    responseN6 = responseDone(conditionDone(2, :)==testSti(2) & conditionDone(3, :)==maskShifts(2));
                    responseN8 = responseDone(conditionDone(2, :)==testSti(3) & conditionDone(3, :)==maskShifts(3));

                    diffRateO = length(find(responseO~=adaptSti))/length(responseO);
                    diffRateN6 = length(find(responseN6~=adaptSti))/length(responseN6);
                    diffRateN8 = length(find(responseN8~=adaptSti))/length(responseN8);

                    bar(app.UIAxesAdaptationTask, [diffRateO, diffRateN6, diffRateN8]);
                    ylim(app.UIAxesAdaptationTask, [0, 1]);
                    app.UIAxesAdaptationTask.XTickLabel = ["O", "N6", "N8"];
                end

                plot(app.UIAxesReactionTime, responseTime(1:n), 'o-k');
                hold(app.UIAxesReactionTime, "on")
                plot(app.UIAxesReactionTime, [1, n], [1.5, 1.5], '-.r')
                hold(app.UIAxesReactionTime, "off")

                app.TrialsDoneLabel.Text = strcat({'Trials Done: '}, {' '}, {num2str(n)});

                drawnow;

                for i = 1:ITIFrames
                    Screen('FillRect', win, 0.5);
                    Screen('Flip', win);
                end

            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%% data storage %%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if strcmp(debugFlag, 'Off')
                generalData.taskDate = taskDate;
                generalData.subjectID = subjectID;
                generalData.subjectAge = subjectAge;
                generalData.subjectGender = subjectGender;
                generalData.visionCorrection = visionCorrection;

                taskData.conditions = {adaptSti; testSti; maskShifts};
                taskData.conditionTotal = conditionTotal;
                taskData.response = response;
                taskData.responseTime = responseTime;
                taskData.eyePositionsXO = eyePositionsXO;
                taskData.eyePositionsYO = eyePositionsYO;
                taskData.eyePositionsXT = eyePositionsXT;
                taskData.eyePositionsYT = eyePositionsYT;

                taskName = stiType;
                taskName(isspace(taskName)) = [];
                fileName = strcat(taskName, '_', subjectID, '_', datestr(now, 'dd-mmm-yyyy-HH-MM-SS'), '.mat');

                save(fileName, 'generalData', 'taskData');
            end

            PsychPortAudio('Stop', beephandle);
            PsychPortAudio('Close', beephandle);

            if strcmp(debugFlag, 'Off')
                crsLiveTrackStopTracking;
                crsLiveTrackClearDataBuffer;
            end

            sca;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesReactionTime = uiaxes(app.UIFigure);
            title(app.UIAxesReactionTime, 'Reaction Time')
            xlabel(app.UIAxesReactionTime, 'Trials')
            ylabel(app.UIAxesReactionTime, 'Time (s)')
            zlabel(app.UIAxesReactionTime, 'Z')
            app.UIAxesAdaptationTask = uiaxes(app.UIFigure);
            title(app.UIAxesAdaptationTask, 'Adaptation Task')
            xlabel(app.UIAxesAdaptationTask, 'Conditions')
            ylabel(app.UIAxesAdaptationTask, 'Proportion Different')
            zlabel(app.UIAxesAdaptationTask, 'Z')
            app.UIAxesEyePosition = uiaxes(app.UIFigure);
            title(app.UIAxesEyePosition, 'Eye Position')
            xlabel(app.UIAxesEyePosition, 'Horizontal Position')
            ylabel(app.UIAxesEyePosition, 'Vertical Position')
            zlabel(app.UIAxesEyePosition, 'Z')
            app.UIAxesEyePosition.YDir = 'reverse';
            app.SubjectInformationPanel = uipanel(app.UIFigure);
            app.SubjectInformationPanel.Title = 'Subject Information';
            app.IDEditFieldLabel = uilabel(app.SubjectInformationPanel);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.SubjectInformationPanel, 'text');
            app.IDEditField.Value = 'Test';
            app.AgeEditFieldLabel = uilabel(app.SubjectInformationPanel);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.SubjectInformationPanel, 'text');
            app.AgeEditField.Value = '0';
            app.VisionCorrectionCheckBox = uicheckbox(app.SubjectInformationPanel);
            app.VisionCorrectionCheckBox.Text = 'Vision Correction';
            app.GenderDropDownLabel = uilabel(app.SubjectInformationPanel);
            app.GenderDropDownLabel.Text = 'Gender';
            app.GenderDropDown = uidropdown(app.SubjectInformationPanel);
            app.GenderDropDown.Items = {'Male', 'Female'};
            app.GenderDropDown.Value = 'Male';
            app.TaskParametersPanel = uipanel(app.UIFigure);
            app.TaskParametersPanel.Title = 'Task Parameters';
            app.secondLabel = uilabel(app.TaskParametersPanel);
            app.secondLabel.Text = 'second';
            app.OnsetAptTimeEditFieldLabel = uilabel(app.TaskParametersPanel);
            app.OnsetAptTimeEditFieldLabel.Text = 'Onset Apt Time';
            app.OnsetAptTimeEditField = uieditfield(app.TaskParametersPanel, 'numeric');
            app.OnsetAptTimeEditField.Value = 30;
            app.secondLabel_2 = uilabel(app.TaskParametersPanel);
            app.secondLabel_2.Text = 'second';
            app.ITIEditFieldLabel = uilabel(app.TaskParametersPanel);
            app.ITIEditFieldLabel.Text = 'ITI';
            app.ITIEditField = uieditfield(app.TaskParametersPanel, 'numeric');
            app.ITIEditField.Value = 0.5;
            app.RepeatEditFieldLabel = uilabel(app.TaskParametersPanel);
            app.RepeatEditFieldLabel.Text = 'Repeat';
            app.RepeatEditField = uieditfield(app.TaskParametersPanel, 'numeric');
            app.RepeatEditField.Value = 10;
            app.secondLabel_3 = uilabel(app.TaskParametersPanel);
            app.secondLabel_3.Text = 'second';
            app.ISIEditFieldLabel = uilabel(app.TaskParametersPanel);
            app.ISIEditFieldLabel.Text = 'ISI';
            app.ISIEditField = uieditfield(app.TaskParametersPanel, 'numeric');
            app.ISIEditField.Value = 0.1;
            app.secondLabel_4 = uilabel(app.TaskParametersPanel);
            app.secondLabel_4.Text = 'second';
            app.TopupAptTimeEditFieldLabel = uilabel(app.TaskParametersPanel);
            app.TopupAptTimeEditFieldLabel.Text = 'Topup Apt Time';
            app.TopupAptTimeEditField = uieditfield(app.TaskParametersPanel, 'numeric');
            app.TopupAptTimeEditField.Value = 5;
            app.secondLabel_5 = uilabel(app.TaskParametersPanel);
            app.secondLabel_5.Text = 'second';
            app.TestStiTimeEditFieldLabel = uilabel(app.TaskParametersPanel);
            app.TestStiTimeEditFieldLabel.Text = 'Test Sti Time';
            app.TestStiTimeEditField = uieditfield(app.TaskParametersPanel, 'numeric');
            app.TestStiTimeEditField.Value = 0.3;
            app.SessionNoEditFieldLabel = uilabel(app.TaskParametersPanel);
            app.SessionNoEditFieldLabel.Text = 'Session No.';
            app.SessionNoEditField = uieditfield(app.TaskParametersPanel, 'numeric');
            app.SessionNoEditField.Value = 1;
            app.DefaultParametersPanel = uipanel(app.UIFigure);
            app.DefaultParametersPanel.Title = 'Default Parameters';
            app.ViewingDistanceEditFieldLabel = uilabel(app.DefaultParametersPanel);
            app.ViewingDistanceEditFieldLabel.Text = 'Viewing Distance';
            app.ViewingDistanceEditField = uieditfield(app.DefaultParametersPanel, 'numeric');
            app.ViewingDistanceEditField.Editable = 'off';
            app.ViewingDistanceEditField.Value = 650;
            app.mmLabel = uilabel(app.DefaultParametersPanel);
            app.mmLabel.Text = 'mm';
            app.MonitorHeightEditFieldLabel = uilabel(app.DefaultParametersPanel);
            app.MonitorHeightEditFieldLabel.Text = 'Monitor Height';
            app.MonitorHeightEditField = uieditfield(app.DefaultParametersPanel, 'numeric');
            app.MonitorHeightEditField.Editable = 'off';
            app.MonitorHeightEditField.Value = 337;
            app.mmLabel_2 = uilabel(app.DefaultParametersPanel);
            app.mmLabel_2.Text = 'mm';
            app.StimuliParametersPanel = uipanel(app.UIFigure);
            app.StimuliParametersPanel.Title = 'Stimuli Parameters';
            app.degreeLabel = uilabel(app.StimuliParametersPanel);
            app.degreeLabel.Text = 'degree';
            app.StimulusHeightEditFieldLabel = uilabel(app.StimuliParametersPanel);
            app.StimulusHeightEditFieldLabel.Text = 'Stimulus Height';
            app.StimulusHeightEditField = uieditfield(app.StimuliParametersPanel, 'numeric');
            app.StimulusHeightEditField.Value = 4;
            app.EccentricityEditFieldLabel = uilabel(app.StimuliParametersPanel);
            app.EccentricityEditFieldLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.StimuliParametersPanel, 'numeric');
            app.EccentricityEditField.Value = 6;
            app.degreeLabel_2 = uilabel(app.StimuliParametersPanel);
            app.degreeLabel_2.Text = 'degree';
            app.StimuliTypeDropDownLabel = uilabel(app.StimuliParametersPanel);
            app.StimuliTypeDropDownLabel.Text = 'Stimuli Type';
            app.StimuliTypeDropDown = uidropdown(app.StimuliParametersPanel);
            app.StimuliTypeDropDown.Items = {'DigitalNum', 'ArabicNum', 'Shapes', 'Moji', 'ScbShapes'};
            app.StimuliTypeDropDown.Value = 'DigitalNum';
            app.StartTaskButton = uibutton(app.UIFigure, 'push');
            app.StartTaskButton.ButtonPushedFcn = createCallbackFcn(app, @StartTaskButtonPushed, true);
            app.StartTaskButton.Text = 'Start Task';
            app.CalibrationButton = uibutton(app.UIFigure, 'push');
            app.CalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationButtonPushed, true);
            app.CalibrationButton.Text = 'Calibration';
            app.TrialsTotalLabel = uilabel(app.UIFigure);
            app.TrialsTotalLabel.Text = 'Trials Total: 0';
            app.TrialsDoneLabel = uilabel(app.UIFigure);
            app.TrialsDoneLabel.Text = 'Trials Done: 0';
            app.DateDatePickerLabel = uilabel(app.UIFigure);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.UIFigure);
            app.DateDatePicker.DisplayFormat = 'dd-MMM-uuuu';
            app.DebugModeDropDownLabel = uilabel(app.UIFigure);
            app.DebugModeDropDownLabel.Text = 'Debug Mode';
            app.DebugModeDropDown = uidropdown(app.UIFigure);
            app.DebugModeDropDown.Items = {'On', 'Off'};
            app.DebugModeDropDown.Value = 'On';
            app.StartPracticeButton = uibutton(app.UIFigure, 'push');
            app.StartPracticeButton.ButtonPushedFcn = createCallbackFcn(app, @StartPracticeButtonPushed, true);
            app.StartPracticeButton.Text = 'Start Practice';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DigitalNumberAdaptationTask_MonitorEye

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end